classdef Image_Filtering_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        InputImageTab              matlab.ui.container.Tab
        Panel_8                    matlab.ui.container.Panel
        InputYourMRIImagejpgpngbnptiffbypressingtheRunbuttonbelowLabel  matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        RunButton                  matlab.ui.control.Button
        ImageFilteringforBrainTumorDetectionusingMRIImageLabel  matlab.ui.control.Label
        Panel_3                    matlab.ui.container.Panel
        ImportedMRIImageLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        Panel_2                    matlab.ui.container.Panel
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        TumorDetectionTab          matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        MRlOriginalImageTab        matlab.ui.container.Tab
        Panel_4                    matlab.ui.container.Panel
        UIAxes2                    matlab.ui.control.UIAxes
        MRIOriginalImageLabel      matlab.ui.control.Label
        FilteredImageTab           matlab.ui.container.Tab
        Panel_5                    matlab.ui.container.Panel
        UIAxes3                    matlab.ui.control.UIAxes
        FilteredMRIImageLabel      matlab.ui.control.Label
        TumorOnlyImageTab          matlab.ui.container.Tab
        Panel_6                    matlab.ui.container.Panel
        UIAxes4                    matlab.ui.control.UIAxes
        TumorClassificationLabel   matlab.ui.control.Label
        MRIwithOutlinedTumorTab    matlab.ui.container.Tab
        Panel_7                    matlab.ui.container.Panel
        UIAxes5                    matlab.ui.control.UIAxes
        MRIwithOutlinedTumorLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Get MRI image from local storage
            global I_Raw I_Information
            
            [FileName, FilePath] = uigetfile({'*.jpg;*.jpeg;*.bnp;*.tiff'}, 'Select a file');
            if isnumeric(FileName)
                msgbox(sprintf('Image Not Found'), 'Error', 'Warning');
                return;
            end
          
            I_Raw = imread(fullfile(FilePath, FileName));
            I_Raw = im2double(I_Raw);
            I_Raw = im2gray(I_Raw);
            imshow(I_Raw, 'Parent', app.UIAxes);
            imshow(I_Raw, 'Parent', app.UIAxes2);
            
            %% Image Filtering 
            I_Filter = imbinarize(I_Raw, 'adaptive', 'Sensitivity', 0.59);
            I_Filter = medfilt2(I_Filter, [5,5]);
            I_Filter = wiener2(I_Filter,[5 5]);
            SE_Filter = strel('disk', 2);
            I_Filter = imclose(I_Filter,SE_Filter);
            imshow(I_Filter, 'Parent', app.UIAxes3);
            
            %% Pre-Generate Elliptical Mask
            X_Size = size(I_Raw, 2);
            Y_Size = size(I_Raw, 1);
            X_Center = Y_Size/2;
            Y_Center = Y_Size/2;
            X_Radius = X_Center - 40;
            Y_Radius = Y_Center - 50;
            [Col, Row] = meshgrid(1:X_Size,1:Y_Size);
            Mask = ((Row - Y_Center).^2 ./ Y_Radius^2) + ((Col - X_Center).^2 ./ X_Radius^2) <= 1;
            
            %% Tumor Isolation
            global I_Detect
            I_Detect = logical(I_Filter .* Mask);
            I_Detect = bwareafilt(I_Detect, [700 4000]);
            SE_Detect = strel('disk', 6);
            I_Detect = imclose(I_Detect,SE_Detect);
            I_Detect = imfill(I_Detect, 'holes');
            imshow(I_Detect, 'Parent', app.UIAxes4);
            
            %% Plot outline of tumor onto original MRI image
            imshow(I_Raw,[], 'Parent', app.UIAxes5);
            hold(app.UIAxes5,"on")
            I_Idx = bwboundaries(I_Detect, 'noholes');
            for k = 1:length(I_Idx)
                plot(app.UIAxes5, I_Idx{k}(:,2),I_Idx{k}(:,1), 'y', 'linewidth', 1.5)
            end
            hold(app.UIAxes5,"off")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputImageTab = uitab(app.TabGroup);
            app.InputImageTab.Title = 'Input Image';
            app.Label = uilabel(app.InputImageTab);
            app.Label.Text = '';
            app.Panel = uipanel(app.InputImageTab);
            app.Panel_2 = uipanel(app.Panel);
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = {'Final Project of '; '"Signal Processing and'; 'Multimedia Services"'; ''; 'Topic: Image Filtering'; ''; ''};
            app.Panel_3 = uipanel(app.InputImageTab);
            app.UIAxes = uiaxes(app.Panel_3);
            zlabel(app.UIAxes, 'Z')
            app.ImportedMRIImageLabel = uilabel(app.Panel_3);
            app.ImportedMRIImageLabel.Text = 'Imported MRI Image';
            app.ImageFilteringforBrainTumorDetectionusingMRIImageLabel = uilabel(app.InputImageTab);
            app.ImageFilteringforBrainTumorDetectionusingMRIImageLabel.Text = 'Image Filtering for Brain Tumor Detection using MRI Image ';
            app.Panel_8 = uipanel(app.InputImageTab);
            app.RunButton = uibutton(app.Panel_8, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label_3 = uilabel(app.Panel_8);
            app.Label_3.Text = {'Group 7:'; ''; 'Rendy Christian'; '(2006529695)'; 'Valerie Olive Suryono'; '(2006574364) '};
            app.InputYourMRIImagejpgpngbnptiffbypressingtheRunbuttonbelowLabel = uilabel(app.Panel_8);
            app.InputYourMRIImagejpgpngbnptiffbypressingtheRunbuttonbelowLabel.Text = {'Input Your MRI Image'; '(.jpg/.png/.bnp/.tiff)'; 'by pressing the "Run"'; ' button below!'};
            app.TumorDetectionTab = uitab(app.TabGroup);
            app.TumorDetectionTab.Title = 'Tumor Detection';
            app.TabGroup2 = uitabgroup(app.TumorDetectionTab);
            app.MRlOriginalImageTab = uitab(app.TabGroup2);
            app.MRlOriginalImageTab.Title = 'MRl Original Image';
            app.MRIOriginalImageLabel = uilabel(app.MRlOriginalImageTab);
            app.MRIOriginalImageLabel.Text = 'MRI Original Image';
            app.Panel_4 = uipanel(app.MRlOriginalImageTab);
            app.UIAxes2 = uiaxes(app.Panel_4);
            zlabel(app.UIAxes2, 'Z')
            app.FilteredImageTab = uitab(app.TabGroup2);
            app.FilteredImageTab.Title = 'Filtered Image';
            app.FilteredMRIImageLabel = uilabel(app.FilteredImageTab);
            app.FilteredMRIImageLabel.Text = 'Filtered MRI Image';
            app.Panel_5 = uipanel(app.FilteredImageTab);
            app.UIAxes3 = uiaxes(app.Panel_5);
            zlabel(app.UIAxes3, 'Z')
            app.TumorOnlyImageTab = uitab(app.TabGroup2);
            app.TumorOnlyImageTab.Title = 'Tumor-Only Image';
            app.TumorClassificationLabel = uilabel(app.TumorOnlyImageTab);
            app.TumorClassificationLabel.Text = 'Tumor Classification';
            app.Panel_6 = uipanel(app.TumorOnlyImageTab);
            app.UIAxes4 = uiaxes(app.Panel_6);
            zlabel(app.UIAxes4, 'Z')
            app.MRIwithOutlinedTumorTab = uitab(app.TabGroup2);
            app.MRIwithOutlinedTumorTab.Title = 'MRI with Outlined Tumor';
            app.MRIwithOutlinedTumorLabel = uilabel(app.MRIwithOutlinedTumorTab);
            app.MRIwithOutlinedTumorLabel.Text = 'MRI with Outlined Tumor';
            app.Panel_7 = uipanel(app.MRIwithOutlinedTumorTab);
            app.UIAxes5 = uiaxes(app.Panel_7);
            zlabel(app.UIAxes5, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Filtering_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end